classdef kyrsach_visual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ButtonGroup     matlab.ui.container.ButtonGroup
        Button          matlab.ui.control.RadioButton
        Button_2        matlab.ui.control.RadioButton
        Button_3        matlab.ui.control.RadioButton
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        signal_1        matlab.ui.control.UIAxes
        signal_2        matlab.ui.control.UIAxes
        Tab_2           matlab.ui.container.Tab
        SPD_1           matlab.ui.control.UIAxes
        SPD_2           matlab.ui.control.UIAxes
        SPD_filter_1    matlab.ui.control.UIAxes
        SPD_filter_2    matlab.ui.control.UIAxes
        Label           matlab.ui.control.Label
        Switch          matlab.ui.control.Switch
        Tab_3           matlab.ui.container.Tab
        Acorr_1         matlab.ui.control.UIAxes
        Acorr_2         matlab.ui.control.UIAxes
        Acorr_filter_1  matlab.ui.control.UIAxes
        Acorr_filter_2  matlab.ui.control.UIAxes
        Tab_4           matlab.ui.container.Tab
        filter_1        matlab.ui.control.UIAxes
        filter_2        matlab.ui.control.UIAxes
        Tab_5           matlab.ui.container.Tab
        Ccorr12         matlab.ui.control.UIAxes
        Ccorr_filter12  matlab.ui.control.UIAxes
        Button_4        matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
            M=dlmread('signal_05_1.txt');X1=M(:,1);X2=M(:,2);
            tmax=1.25;
            Fd=500;
            T=1/Fd;
            t=0:T:tmax-T;
            N=Fd*tmax;
            
            %построение входных сигналов
            plot(app.signal_1,t,X1)
            plot(app.signal_2,t,X2)
            app.signal_1.XLim=[0 tmax];
            app.signal_2.XLim=[0 tmax];
            app.signal_1.YLim=[-2 4];
            app.signal_2.YLim=[-2 4];
            
            %расчет АКФ для входных сигналов
            tcf=-tmax+T:T:tmax-T;
            acf1=xcorr(X1,'coeff');
            acf2=xcorr(X2,'coeff');
            plot(app.Acorr_1,tcf,acf1)
            plot(app.Acorr_2,tcf,acf2)
            app.Acorr_1.YLim=[-1 +1];
            app.Acorr_2.YLim=[-1 +1];
             app.Acorr_1.XLim=[-tmax/2 +tmax/2];
            app.Acorr_2.XLim=[-tmax/2 +tmax/2];
            
            %расчет ВКФ для входных сигналов
            ccf12=xcorr(X1,X2,'coeff');
            plot(app.Ccorr12,tcf,ccf12)
            app.Ccorr12.YLim=[-1 +1];
             app.Ccorr12.XLim=[-tmax/2 +tmax/2];
            
            %расчет СПМ для входных сигналов
            nfft=1024;
            df=Fd/N;
            for i=1:(N/2)
                f(i)=df*(i-1);
            end
            if app.Button.Value==true
             [pxx1,f] = periodogram(X1,rectwin(length(X1)),nfft,Fd);
             [pxx2,f] = periodogram(X2,rectwin(length(X2)),nfft,Fd);
          elseif app.Button_2.Value==true
             [pxx1,f] = periodogram(X1,bartlett(length(X1)),nfft,Fd);
             [pxx2,f] = periodogram(X2,bartlett(length(X2)),nfft,Fd);
          elseif app.Button_3.Value==true
             [pxx1,f] = periodogram(X1,hamming(length(X1)),nfft,Fd);
             [pxx2,f] = periodogram(X2,hamming(length(X2)),nfft,Fd);
            end
            plot(app.SPD_1,f,pxx1)
            plot(app.SPD_2,f,pxx2)
                if app.Switch.Value=='Логарифмический'
                    app.SPD_1.YScale='log';
                    app.SPD_2.YScale='log';
                    app.SPD_1.YLabel.String = 'СПМ,дБ';
                    app.SPD_2.YLabel.String = 'СПМ,дБ';
                elseif app.Switch.Value=='Линейный'
                    app.SPD_1.Yscale='lin';
                    app.SPD_2.Yscale='lin';
                end
            
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
            M=dlmread('signal_05_1.txt');X1=M(:,1);X2=M(:,2);
            load('coef.mat');
            tmax=1.25;
            Fd=500;
            T=1/Fd;
            t=0:T:tmax-T;
            N=Fd*tmax;
            for i=1:11
                a(i)=Den(i);
                b(i)=Num(i);
            end
            
            %фильтрация входных сигналов
            Y1=filter(b,a,X1);
            Y2=filter(b,a,X2);
            plot(app.filter_1,t,Y1)
            plot(app.filter_2,t,Y2)
            app.filter_1.XLim=[0 tmax];
            app.filter_2.XLim=[0 tmax];
            app.filter_1.YLim=[-2 4];
            app.filter_2.YLim=[-2 4];
            
            %расчет АКФ для выходных сигналов
            tcf=-tmax+T:T:tmax-T;
            acf_filt1=xcorr(Y1,'coeff');
            acf_filt2=xcorr(Y2,'coeff');
            plot(app.Acorr_filter_1,tcf,acf_filt1)
            plot(app.Acorr_filter_2,tcf,acf_filt2)
            app.Acorr_filter_1.YLim=[-1 +1];
            app.Acorr_filter_2.YLim=[-1 +1];
             app.Acorr_filter_1.XLim=[-tmax/2 +tmax/2];
            app.Acorr_filter_2.XLim=[-tmax/2 +tmax/2];
            
            %расчет ВКФ для выходных сигналов
            ccf_filt12=xcorr(Y1,Y2,'coeff');
            plot(app.Ccorr_filter12,tcf,ccf_filt12)
            app.Ccorr_filter12.YLim=[-1 +1];
            app.Ccorr_filter12.XLim=[-tmax/2 +tmax/2];
            
            %расчет СПМ для выходных сигналов
            nfft=1024;
             df=Fd/N;
            for i=1:(N/2)-1
                f(i)=df*(i-1);
            end
          if app.Button.Value==true
             [pxx12,f] = periodogram(Y1,rectwin(length(Y1)),nfft,Fd);
             [pxx22,f] = periodogram(Y2,rectwin(length(Y2)),nfft,Fd);
             [pxx11,f] = periodogram(X1,rectwin(length(X1)),nfft,Fd);
             [pxx21,f] = periodogram(X2,rectwin(length(X2)),nfft,Fd);
          elseif app.Button_2.Value==true
             [pxx12,f] = periodogram(Y1,bartlett(length(Y1)),nfft,Fd);
             [pxx22,f] = periodogram(Y2,bartlett(length(Y2)),nfft,Fd);
             [pxx11,f] = periodogram(X1,bartlett(length(X1)),nfft,Fd);
             [pxx21,f] = periodogram(X2,bartlett(length(X2)),nfft,Fd);
          elseif app.Button_3.Value==true
             [pxx12,f] = periodogram(Y1,hamming(length(Y1)),nfft,Fd);
             [pxx22,f] = periodogram(Y2,hamming(length(Y2)),nfft,Fd);
             [pxx11,f] = periodogram(X1,hamming(length(X1)),nfft,Fd);
             [pxx21,f] = periodogram(X2,hamming(length(X2)),nfft,Fd);
          end
                    plot(app.SPD_filter_1,f,pxx12)
                    plot(app.SPD_filter_2,f,pxx22)
                    plot(app.SPD_1,f,pxx11)
                    plot(app.SPD_2,f,pxx21)
            if app.Switch.Value=='Логарифмический'
                   app.SPD_filter_1.YScale='log';
                    app.SPD_filter_2.YScale='log';
                     app.SPD_1.YScale='log';
                    app.SPD_2.YScale='log';
                     app.SPD_1.YLabel.String = 'СПМ,дБ';
                    app.SPD_2.YLabel.String = 'СПМ,дБ';
                     app.SPD_filter_1.YLabel.String = 'СПМ,дБ';
                    app.SPD_filter_2.YLabel.String = 'СПМ,дБ';
                elseif app.Switch.Value=='Линейный'
                    app.SPD_filter_1.YScale='lin';
                    app.SPD_filter_2.YScale='lin';
                    app.SPD_1.Yscale='lin';
                    app.SPD_2.Yscale='lin';
                  
                end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Сглаживающее окно';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Прямоугольное';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Треугольное';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Хемминга';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Входной сигнал';
            app.signal_1 = uiaxes(app.Tab);
            title(app.signal_1, 'ЭКГ-1')
            xlabel(app.signal_1, 't,c')
            ylabel(app.signal_1, 'ЭКГ,мВ')
            app.signal_2 = uiaxes(app.Tab);
            title(app.signal_2, 'ЭКГ-2')
            xlabel(app.signal_2, 't,c')
            ylabel(app.signal_2, 'ЭКГ,мВ')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'СПМ';
            app.SPD_1 = uiaxes(app.Tab_2);
            title(app.SPD_1, 'СПМ входного ЭКГ-1')
            xlabel(app.SPD_1, 'f,Гц')
            ylabel(app.SPD_1, 'СПМ,мВ^2/Гц')
            app.SPD_2 = uiaxes(app.Tab_2);
            title(app.SPD_2, 'СПМ входного ЭКГ-2')
            xlabel(app.SPD_2, 'f,Гц')
            ylabel(app.SPD_2, 'СПМ,мВ^2/Гц')
            app.SPD_filter_1 = uiaxes(app.Tab_2);
            title(app.SPD_filter_1, 'СПМ отфильтрованного ЭКГ-1')
            xlabel(app.SPD_filter_1, 'f,Гц')
            ylabel(app.SPD_filter_1, 'СПМ,мВ^2/Гц')
            app.SPD_filter_2 = uiaxes(app.Tab_2);
            title(app.SPD_filter_2, 'СПМ отфильтрованного ЭКГ-2')
            xlabel(app.SPD_filter_2, 'f,Гц')
            ylabel(app.SPD_filter_2, 'СПМ,мВ^2/Гц')
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = 'Масштаб';
            app.Switch = uiswitch(app.Tab_2, 'slider');
            app.Switch.Items = {'Линейный', 'Логарифмический'};
            app.Switch.Value = 'Линейный';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'АКФ';
            app.Acorr_1 = uiaxes(app.Tab_3);
            title(app.Acorr_1, 'Автокорреляционная функция входного ЭКГ-1')
            xlabel(app.Acorr_1, 'τ,c')
            app.Acorr_2 = uiaxes(app.Tab_3);
            title(app.Acorr_2, 'Автокорреляционная функция входного ЭКГ-2')
            xlabel(app.Acorr_2, 'τ,c')
            app.Acorr_filter_1 = uiaxes(app.Tab_3);
            title(app.Acorr_filter_1, 'Автокорреляционная функция отфильтрованного ЭКГ-1')
            xlabel(app.Acorr_filter_1, 'τ,c')
            app.Acorr_filter_2 = uiaxes(app.Tab_3);
            title(app.Acorr_filter_2, 'Автокорреляционная функция отфильтрованного ЭКГ-2')
            xlabel(app.Acorr_filter_2, 'τ,c')
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Фильтрация';
            app.filter_1 = uiaxes(app.Tab_4);
            title(app.filter_1, 'Фильтрация ЭКГ-1')
            xlabel(app.filter_1, 't,c')
            ylabel(app.filter_1, 'ЭКГ,мВ')
            app.filter_2 = uiaxes(app.Tab_4);
            title(app.filter_2, 'Фильтрация ЭКГ-2')
            xlabel(app.filter_2, 't,c')
            ylabel(app.filter_2, 'ЭКГ,мВ')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'ВКФ';
            app.Ccorr12 = uiaxes(app.Tab_5);
            title(app.Ccorr12, 'Взаимно корреляционная функция сигналов до фильтрации')
            xlabel(app.Ccorr12, 'τ,c')
            app.Ccorr_filter12 = uiaxes(app.Tab_5);
            title(app.Ccorr_filter12, 'Взаимно корреляционная функция сигналов после фильтрации')
            xlabel(app.Ccorr_filter12, 'τ,c')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Загрузить данные';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Фильтрация';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kyrsach_visual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end